classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TrainandTestButton             matlab.ui.control.Button
        ClassifyButton                 matlab.ui.control.Button
        NeuralNetworkDropDownLabel     matlab.ui.control.Label
        NeuralNetworkDropDown          matlab.ui.control.DropDown
        DatasetDropDownLabel           matlab.ui.control.Label
        DatasetDropDown                matlab.ui.control.DropDown
        TrainFunctionDropDownLabel     matlab.ui.control.Label
        TrainFunctionDropDown          matlab.ui.control.DropDown
        HiddenValueDropDownLabel       matlab.ui.control.Label
        HiddenValueDropDown            matlab.ui.control.DropDown
        ActivationFunctionDropDownLabel  matlab.ui.control.Label
        ActivationFunctionDropDown     matlab.ui.control.DropDown
        SensitivityLabel               matlab.ui.control.Label
        SensitivityLabelValue          matlab.ui.control.Label
        SpecificityLabel               matlab.ui.control.Label
        SpecificityLabelValue          matlab.ui.control.Label
        PreIctalsDetectedLabel         matlab.ui.control.Label
        PreIctalsDetectedLabelValue    matlab.ui.control.Label
        IctalsDetectedLabel            matlab.ui.control.Label
        IctalsDetectedLabelValue       matlab.ui.control.Label
        DatasetDropDown_2Label         matlab.ui.control.Label
        DatasetDropDown_2              matlab.ui.control.DropDown
        SensitivityLabel_2             matlab.ui.control.Label
        SensitivityLabelValue_2        matlab.ui.control.Label
        SpecificityLabel_2             matlab.ui.control.Label
        SpecificityLabelValue_2        matlab.ui.control.Label
        PreIctalsDetectedLabel_2       matlab.ui.control.Label
        PreIctalsDetectedLabelValue_2  matlab.ui.control.Label
        IctalsDetectedLabel_2          matlab.ui.control.Label
        IctalsDetectedLabelValue_2     matlab.ui.control.Label
        NeuralNetworkDropDown_2Label   matlab.ui.control.Label
        NeuralNetworkDropDown_2        matlab.ui.control.DropDown
        LoadNNandClassifyButton        matlab.ui.control.Button
        PreProcessingDropDownLabel     matlab.ui.control.Label
        PreProcessingDropDown          matlab.ui.control.DropDown
        AccuracyLabel                  matlab.ui.control.Label
        AccuracyLabelValue             matlab.ui.control.Label
        AccuracyLabel_2                matlab.ui.control.Label
        AccuracyLabelValue_2           matlab.ui.control.Label
    end


    methods (Access = private)

        % Button pushed function: TrainandTestButton
        function TrainandTestButtonPushed(app, event)
            dataset = '';
            neuralNetwork = '';
            trainFunction = '';
            hiddenValue = 1;
            activation = '';
            pre = '';
            
            
             if strcmp(app.PreProcessingDropDown.Value,'A')
                pre = 'A';
             elseif strcmp(app.PreProcessingDropDown.Value,'B')
                pre = 'B';
             elseif strcmp(app.PreProcessingDropDown.Value,'C')
                pre = 'C';
             end
            
            
             if strcmp(app.DatasetDropDown.Value,'44202')
                dataset = '44202.mat';
             elseif strcmp(app.DatasetDropDown.Value,'54802')
                dataset = '54802.mat';
             end
             
             
             if strcmp(app.NeuralNetworkDropDown.Value,'recnet')
                neuralNetwork = 'recnet';
             elseif strcmp(app.NeuralNetworkDropDown.Value,'feedfo')
                neuralNetwork = 'feedfo';
             elseif strcmp(app.NeuralNetworkDropDown.Value,'elman')
                neuralNetwork = 'elman';
             end
             
             if strcmp(app.TrainFunctionDropDown.Value,'traincgp')
                trainFunction = 'traincgp';
             elseif strcmp(app.TrainFunctionDropDown.Value,'trainscg')
                trainFunction = 'trainscg';
             elseif strcmp(app.TrainFunctionDropDown.Value,'traincgb')
                trainFunction = 'traincgb';
             end
             
             if strcmp(app.ActivationFunctionDropDown.Value,'purelin')
                activation = 'purelin';
             %elseif app.ActivationFunctionDropDown.Value == 'trainscg'
             %   activationFunction = 'trainscg';
             %elseif app.ActivationFunctionDropDown.Value == 'trainbr'
             %   activationFunction = 'trainbr';
             end
             
             if strcmp(app.HiddenValueDropDown.Value,'1')
                hiddenValue = 1;
             elseif strcmp(app.HiddenValueDropDown.Value,'10')
                hiddenValue = 10;
             elseif strcmp(app.HiddenValueDropDown.Value,'20')
                hiddenValue = 20;
             elseif strcmp(app.HiddenValueDropDown.Value,'30')
                hiddenValue = 30;
             end
            
            TrainAndTest();
            app.SensitivityLabelValue.Text = strcat(num2str(floor(sensi * 100)), ' %');
            app.SpecificityLabelValue.Text = strcat(num2str(floor(speci * 100)), ' %');
            app.PreIctalsDetectedLabelValue.Text = strcat(num2str(floor(PreicPerc)), ' %');
            app.IctalsDetectedLabelValue.Text = strcat(num2str(floor(IctalPerc)), ' %');
            app.IctalsAccuracyLabelValue.Text = strcat(num2str(floor(AC)), ' %');
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
             dataset = '';
             neuralNetwork = ''; 
             pre = '';
             option = 1;
            
            
             if strcmp(app.PreProcessingDropDown.Value,'A')
                pre = 'A';
             elseif strcmp(app.PreProcessingDropDown.Value,'B')
                pre = 'B';
             elseif strcmp(app.PreProcessingDropDown.Value,'C')
                pre = 'C';
             end
            
             if app.DatasetDropDown_2.Value == '44202'
                dataset = '44202.mat';
             elseif app.DatasetDropDown_2.Value == '54802'
                dataset = '54802.mat';
             end
             
             if app.NeuralNetworkDropDown_2.Value == 'recnet_1'
                neuralNetwork = 'recnet_1';
             elseif app.NeuralNetworkDropDown_2.Value == 'feedfo_1'
                neuralNetwork = 'feedfo_1';
             end
            
            
            Classify()
            app.SensitivityLabelValue_2.Text = strcat(num2str(floor(sensi * 100)), ' %');
            app.SpecificityLabelValue_2.Text = strcat(num2str(floor(speci * 100)), ' %');
            app.PreIctalsDetectedLabelValue_2.Text = strcat(num2str(floor(PreicPerc)), ' %');
            app.IctalsDetectedLabelValue_2.Text = strcat(num2str(floor(IctalPerc)), ' %');
            app.IctalsAccuracyLabelValue_2.Text = strcat(num2str(floor(AC)), ' %');
        end

        % Button pushed function: LoadNNandClassifyButton
        function LoadNNandClassifyButtonPushed(app, event)
             dataset = '';
             neuralNetwork = '';
             pre = '';
             option = 0;
            
            
             if strcmp(app.PreProcessingDropDown.Value,'A')
                pre = 'A';
             elseif strcmp(app.PreProcessingDropDown.Value,'B')
                pre = 'B';
             elseif strcmp(app.PreProcessingDropDown.Value,'C')
                pre = 'C';
             end
            
            
             if app.DatasetDropDown_2.Value == '44202'
                dataset = '44202.mat';
             elseif app.DatasetDropDown_2.Value == '54802'
                dataset = '54802.mat';
             end
             
            Load()
            app.SensitivityLabelValue_2.Text = strcat(num2str(floor(sensi * 100)), ' %');
            app.SpecificityLabelValue_2.Text = strcat(num2str(floor(speci * 100)), ' %');
            app.PreIctalsDetectedLabelValue_2.Text = strcat(num2str(floor(PreicPerc)), ' %');
            app.IctalsDetectedLabelValue_2.Text = strcat(num2str(floor(IctalPerc)), ' %');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TrainandTestButton = uibutton(app.UIFigure, 'push');
            app.TrainandTestButton.ButtonPushedFcn = createCallbackFcn(app, @TrainandTestButtonPushed, true);
            app.TrainandTestButton.Text = 'Train and Test';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.NeuralNetworkDropDownLabel = uilabel(app.UIFigure);
            app.NeuralNetworkDropDownLabel.Text = 'Neural Network';
            app.NeuralNetworkDropDown = uidropdown(app.UIFigure);
            app.NeuralNetworkDropDown.Items = {'feedfo', 'recnet', 'elman'};
            app.NeuralNetworkDropDown.Value = 'feedfo';
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.Items = {'44202', '54802'};
            app.DatasetDropDown.Value = '44202';
            app.TrainFunctionDropDownLabel = uilabel(app.UIFigure);
            app.TrainFunctionDropDownLabel.Text = 'TrainFunction';
            app.TrainFunctionDropDown = uidropdown(app.UIFigure);
            app.TrainFunctionDropDown.Items = {'traincgp', 'trainscg', 'traincgb'};
            app.TrainFunctionDropDown.Value = 'traincgp';
            app.HiddenValueDropDownLabel = uilabel(app.UIFigure);
            app.HiddenValueDropDownLabel.Text = 'HiddenValue';
            app.HiddenValueDropDown = uidropdown(app.UIFigure);
            app.HiddenValueDropDown.Items = {'1', '10', '20', '30'};
            app.HiddenValueDropDown.Value = '1';
            app.ActivationFunctionDropDownLabel = uilabel(app.UIFigure);
            app.ActivationFunctionDropDownLabel.Text = 'ActivationFunction';
            app.ActivationFunctionDropDown = uidropdown(app.UIFigure);
            app.ActivationFunctionDropDown.Items = {'purelin'};
            app.ActivationFunctionDropDown.Value = 'purelin';
            app.SensitivityLabel = uilabel(app.UIFigure);
            app.SensitivityLabel.Text = 'Sensitivity :';
            app.SensitivityLabelValue = uilabel(app.UIFigure);
            app.SensitivityLabelValue.Text = '';
            app.SpecificityLabel = uilabel(app.UIFigure);
            app.SpecificityLabel.Text = 'Specificity : ';
            app.SpecificityLabelValue = uilabel(app.UIFigure);
            app.SpecificityLabelValue.Text = '';
            app.PreIctalsDetectedLabel = uilabel(app.UIFigure);
            app.PreIctalsDetectedLabel.Text = 'PreIctals detected :';
            app.PreIctalsDetectedLabelValue = uilabel(app.UIFigure);
            app.PreIctalsDetectedLabelValue.Text = '';
            app.IctalsDetectedLabel = uilabel(app.UIFigure);
            app.IctalsDetectedLabel.Text = 'Ictals detected :';
            app.IctalsDetectedLabelValue = uilabel(app.UIFigure);
            app.IctalsDetectedLabelValue.Text = '';
            app.DatasetDropDown_2Label = uilabel(app.UIFigure);
            app.DatasetDropDown_2Label.Text = 'Dataset';
            app.DatasetDropDown_2 = uidropdown(app.UIFigure);
            app.DatasetDropDown_2.Items = {'44202', '54802'};
            app.DatasetDropDown_2.Value = '44202';
            app.SensitivityLabel_2 = uilabel(app.UIFigure);
            app.SensitivityLabel_2.Text = 'Sensitivity :';
            app.SensitivityLabelValue_2 = uilabel(app.UIFigure);
            app.SensitivityLabelValue_2.Text = '';
            app.SpecificityLabel_2 = uilabel(app.UIFigure);
            app.SpecificityLabel_2.Text = 'Specificity : ';
            app.SpecificityLabelValue_2 = uilabel(app.UIFigure);
            app.SpecificityLabelValue_2.Text = '';
            app.PreIctalsDetectedLabel_2 = uilabel(app.UIFigure);
            app.PreIctalsDetectedLabel_2.Text = 'PreIctals detected :';
            app.PreIctalsDetectedLabelValue_2 = uilabel(app.UIFigure);
            app.PreIctalsDetectedLabelValue_2.Text = '';
            app.IctalsDetectedLabel_2 = uilabel(app.UIFigure);
            app.IctalsDetectedLabel_2.Text = 'Ictals detected :';
            app.IctalsDetectedLabelValue_2 = uilabel(app.UIFigure);
            app.IctalsDetectedLabelValue_2.Text = '';
            app.NeuralNetworkDropDown_2Label = uilabel(app.UIFigure);
            app.NeuralNetworkDropDown_2Label.Text = 'Neural Network';
            app.NeuralNetworkDropDown_2 = uidropdown(app.UIFigure);
            app.NeuralNetworkDropDown_2.Items = {'feedfo_1', 'recnet_1'};
            app.NeuralNetworkDropDown_2.Value = 'feedfo_1';
            app.LoadNNandClassifyButton = uibutton(app.UIFigure, 'push');
            app.LoadNNandClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNNandClassifyButtonPushed, true);
            app.LoadNNandClassifyButton.Text = 'Load NN and Classify';
            app.PreProcessingDropDownLabel = uilabel(app.UIFigure);
            app.PreProcessingDropDownLabel.Text = 'Pre-Processing';
            app.PreProcessingDropDown = uidropdown(app.UIFigure);
            app.PreProcessingDropDown.Items = {'A', 'B'};
            app.PreProcessingDropDown.Value = 'A';
            app.AccuracyLabel = uilabel(app.UIFigure);
            app.AccuracyLabel.Text = 'Accuracy :';
            app.AccuracyLabelValue = uilabel(app.UIFigure);
            app.AccuracyLabelValue.Text = '';
            app.AccuracyLabel_2 = uilabel(app.UIFigure);
            app.AccuracyLabel_2.Text = 'Accuracy :';
            app.AccuracyLabelValue_2 = uilabel(app.UIFigure);
            app.AccuracyLabelValue_2.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end